classdef NullclinePlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        SampleSystemsDropDownLabel  matlab.ui.control.Label
        SampleSystemsDropDown       matlab.ui.control.DropDown
        kbS0EditField_2Label        matlab.ui.control.Label
        kbS0EditField               matlab.ui.control.NumericEditField
        KaS0S0EditFieldLabel        matlab.ui.control.Label
        KaS0S0EditField             matlab.ui.control.NumericEditField
        KrS1S0EditField_2Label      matlab.ui.control.Label
        KrS1S0EditField             matlab.ui.control.NumericEditField
        KaS1S0EditField_2Label      matlab.ui.control.Label
        KaS1S0EditField             matlab.ui.control.NumericEditField
        KrS0S0EditFieldLabel        matlab.ui.control.Label
        KrS0S0EditField             matlab.ui.control.NumericEditField
        KaS1S1EditFieldLabel        matlab.ui.control.Label
        KaS1S1EditField             matlab.ui.control.NumericEditField
        KrS0S1EditFieldLabel        matlab.ui.control.Label
        KrS0S1EditField             matlab.ui.control.NumericEditField
        KaS0S1EditFieldLabel        matlab.ui.control.Label
        KaS0S1EditField             matlab.ui.control.NumericEditField
        ClearButton                 matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        kdEditFieldLabel            matlab.ui.control.Label
        kdEditField                 matlab.ui.control.NumericEditField
        kaS0EditFieldLabel          matlab.ui.control.Label
        kaS0EditField               matlab.ui.control.NumericEditField
        kbS1EditFieldLabel          matlab.ui.control.Label
        kbS1EditField               matlab.ui.control.NumericEditField
        KrS1S1EditFieldLabel        matlab.ui.control.Label
        KrS1S1EditField             matlab.ui.control.NumericEditField
        kaS1EditFieldLabel          matlab.ui.control.Label
        kaS1EditField               matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            cla (app.UIAxes);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            P0 = 1;
            P1 = 1;
            nr = 30;
            nc = 2;
            Ko = 0.033;
            Kao = 1;
            syms S0 S1
            dS0 = P0*(app.kbS0EditField.Value*Ko*nr+app.kaS0EditField.Value*Kao*nr*(app.KaS0S0EditField.Value*S0)^nc+app.kaS0EditField.Value*Kao*nr*(app.KaS1S0EditField.Value*S1)^nc)/(1+Ko*nr+Kao*nr*(app.KaS0S0EditField.Value*S0)^nc+Kao*nr*(app.KaS1S0EditField.Value*S1)^nc+(app.KrS0S0EditField.Value*S0)^nc+(app.KrS1S0EditField.Value*S1)^nc)-app.kdEditField.Value*S0;
            dS1 = P1*(app.kbS1EditField.Value*Ko*nr+app.kaS1EditField.Value*Kao*nr*(app.KaS1S1EditField.Value*S1)^nc+app.kaS1EditField.Value*Kao*nr*(app.KaS0S1EditField.Value*S0)^nc)/(1+Ko*nr+Kao*nr*(app.KaS1S1EditField.Value*S1)^nc+Kao*nr*(app.KaS0S1EditField.Value*S0)^nc+(app.KrS1S1EditField.Value*S1)^nc+(app.KrS0S1EditField.Value*S0)^nc)-app.kdEditField.Value*S1;
            [X, Y] = meshgrid(0:1:50,0:1:50); 
            G0 = subs(dS0, [S0 S1], {X,Y});
            G1 = subs(dS1, [S0 S1], {X,Y});
            quiver(app.UIAxes,X,Y,G0,G1);
            hold (app.UIAxes,'on');

            syms S0new S1new
            dS0new = (P0*(app.kbS0EditField.Value*Ko*nr+app.kaS0EditField.Value*Kao*nr*(app.KaS0S0EditField.Value*S0new)^nc+app.kaS0EditField.Value*Kao*nr*(app.KaS1S0EditField.Value*S1new)^nc)/(1+Ko*nr+Kao*nr*(app.KaS0S0EditField.Value*S0new)^nc+Kao*nr*(app.KaS1S0EditField.Value*S1new)^nc+(app.KrS0S0EditField.Value*S0new)^nc+(app.KrS1S0EditField.Value*S1new)^nc)-app.kdEditField.Value*S0new) == 0;
            dS1new = (P1*(app.kbS1EditField.Value*Ko*nr+app.kaS1EditField.Value*Kao*nr*(app.KaS1S1EditField.Value*S1new)^nc+app.kaS1EditField.Value*Kao*nr*(app.KaS0S1EditField.Value*S0new)^nc)/(1+Ko*nr+Kao*nr*(app.KaS1S1EditField.Value*S1new)^nc+Kao*nr*(app.KaS0S1EditField.Value*S0new)^nc+(app.KrS1S1EditField.Value*S1new)^nc+(app.KrS0S1EditField.Value*S0new)^nc)-app.kdEditField.Value*S1new) == 0;
              
            sol0 = solve(dS0new,S0new);
            sol1 = solve(dS1new,S1new);
            G0new = zeros(1,50);
            G1new = zeros(1,50);
            for new = 0:50
            G0new(new+1) = subs(sol0(1), S1new, new);
            G1new(new+1) = subs(sol1(1), S0new, new);
            end
            xax = 0:50;
            plot(app.UIAxes,G0new(:),xax);
            plot(app.UIAxes,xax,G1new(:));
            legend(app.UIAxes,'Field Vectors','dS0/dt = 0','dS1/dt = 0');

    
        end

        % Value changed function: SampleSystemsDropDown
        function SampleSystemsDropDownValueChanged(app, event)
            value = app.SampleSystemsDropDown.Value;
            
            if strcmp(value,'Repressor')
                app.kdEditField.Value = 0.0075;
                app.kaS0EditField.Value = 0;
                app.kaS1EditField.Value = 0;
                app.kbS0EditField.Value = 0.3;
                app.kbS1EditField.Value = 0.3;
                app.KaS0S0EditField.Value = 0;
                app.KaS1S0EditField.Value = 0;  
                app.KrS0S0EditField.Value = 0;
                app.KrS1S0EditField.Value = .5;
                app.KaS1S1EditField.Value = 0;
                app.KaS0S1EditField.Value = 0; 
                app.KrS1S1EditField.Value = 0;
                app.KrS0S1EditField.Value = .5;
            end
            if strcmp(value,'S0 Represses S1')
                app.kdEditField.Value = 0.0075;
                app.kaS0EditField.Value = 0;
                app.kaS1EditField.Value = 0;
                app.kbS0EditField.Value = 0.05;
                app.kbS1EditField.Value = 0;
                app.KaS0S0EditField.Value = 0;
                app.KaS1S0EditField.Value = 0;  
                app.KrS0S0EditField.Value = 0;
                app.KrS1S0EditField.Value = 0.5;
                app.KaS1S1EditField.Value = 0;
                app.KaS0S1EditField.Value = 0; 
                app.KrS1S1EditField.Value = 0;
                app.KrS0S1EditField.Value = 0;
            end
            
            if strcmp(value,'Oscillator')
                app.kdEditField.Value = 0.0075;
                app.kaS0EditField.Value = 0;
                app.kaS1EditField.Value = 0.25;
                app.kbS0EditField.Value = .5;
                app.kbS1EditField.Value = 1e-4;
                app.KaS0S0EditField.Value = 0;
                app.KaS1S0EditField.Value = 0;  
                app.KrS0S0EditField.Value = 0;
                app.KrS1S0EditField.Value = .5;
                app.KaS1S1EditField.Value = 0;
                app.KaS0S1EditField.Value = .5; 
                app.KrS1S1EditField.Value = 0;
                app.KrS0S1EditField.Value = 0;
            end
    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Nullcline Plot'; ''})
            xlabel(app.UIAxes, 'S0')
            ylabel(app.UIAxes, 'SI')
            app.SampleSystemsDropDownLabel = uilabel(app.UIFigure);
            app.SampleSystemsDropDownLabel.Text = {'Sample Systems'; ''};
            app.SampleSystemsDropDown = uidropdown(app.UIFigure);
            app.SampleSystemsDropDown.Items = {'Select Example', 'Oscillator', 'Repressor', 'S0 Represses S1', ''};
            app.SampleSystemsDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleSystemsDropDownValueChanged, true);
            app.SampleSystemsDropDown.Value = 'Select Example';
            app.kbS0EditField_2Label = uilabel(app.UIFigure);
            app.kbS0EditField_2Label.Text = 'kbS0';
            app.kbS0EditField = uieditfield(app.UIFigure, 'numeric');
            app.KaS0S0EditFieldLabel = uilabel(app.UIFigure);
            app.KaS0S0EditFieldLabel.Text = 'KaS0S0';
            app.KaS0S0EditField = uieditfield(app.UIFigure, 'numeric');
            app.KrS1S0EditField_2Label = uilabel(app.UIFigure);
            app.KrS1S0EditField_2Label.Text = 'KrS1S0';
            app.KrS1S0EditField = uieditfield(app.UIFigure, 'numeric');
            app.KaS1S0EditField_2Label = uilabel(app.UIFigure);
            app.KaS1S0EditField_2Label.Text = 'KaS1S0';
            app.KaS1S0EditField = uieditfield(app.UIFigure, 'numeric');
            app.KrS0S0EditFieldLabel = uilabel(app.UIFigure);
            app.KrS0S0EditFieldLabel.Text = 'KrS0S0';
            app.KrS0S0EditField = uieditfield(app.UIFigure, 'numeric');
            app.KaS1S1EditFieldLabel = uilabel(app.UIFigure);
            app.KaS1S1EditFieldLabel.Text = 'KaS1S1';
            app.KaS1S1EditField = uieditfield(app.UIFigure, 'numeric');
            app.KrS0S1EditFieldLabel = uilabel(app.UIFigure);
            app.KrS0S1EditFieldLabel.Text = 'KrS0S1';
            app.KrS0S1EditField = uieditfield(app.UIFigure, 'numeric');
            app.KaS0S1EditFieldLabel = uilabel(app.UIFigure);
            app.KaS0S1EditFieldLabel.Text = 'KaS0S1';
            app.KaS0S1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = {'Clear'; ''};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = {'Plot'; ''};
            app.kdEditFieldLabel = uilabel(app.UIFigure);
            app.kdEditFieldLabel.Text = 'kd';
            app.kdEditField = uieditfield(app.UIFigure, 'numeric');
            app.kaS0EditFieldLabel = uilabel(app.UIFigure);
            app.kaS0EditFieldLabel.Text = 'kaS0';
            app.kaS0EditField = uieditfield(app.UIFigure, 'numeric');
            app.kbS1EditFieldLabel = uilabel(app.UIFigure);
            app.kbS1EditFieldLabel.Text = 'kbS1';
            app.kbS1EditField = uieditfield(app.UIFigure, 'numeric');
            app.KrS1S1EditFieldLabel = uilabel(app.UIFigure);
            app.KrS1S1EditFieldLabel.Text = 'KrS1S1';
            app.KrS1S1EditField = uieditfield(app.UIFigure, 'numeric');
            app.kaS1EditFieldLabel = uilabel(app.UIFigure);
            app.kaS1EditFieldLabel.Text = 'kaS1';
            app.kaS1EditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = NullclinePlotter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end